classdef cniQA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        mainPlot               matlab.ui.control.UIAxes
        FileMenu               matlab.ui.container.Menu
        LoadQAdataMenu         matlab.ui.container.Menu
        SaveQAdataMenu         matlab.ui.container.Menu
        RefreshMenu            matlab.ui.container.Menu
        QuitMenu               matlab.ui.container.Menu
        FlywheelMenu           matlab.ui.container.Menu
        SigninMenu             matlab.ui.container.Menu
        ProjectlabelMenu       matlab.ui.container.Menu
        ToolsMenu              matlab.ui.container.Menu
        App2workspaceMenu      matlab.ui.container.Menu
        PlotMenu               matlab.ui.container.Menu
        TBDMenu                matlab.ui.container.Menu
        SelectdataPanel        matlab.ui.container.Panel
        RetrieveQAButton       matlab.ui.control.Button
        SinceDatePickerLabel   matlab.ui.control.Label
        SinceDatePicker        matlab.ui.control.DatePicker
        UntilDatePickerLabel   matlab.ui.control.Label
        UntilDatePicker        matlab.ui.control.DatePicker
        AnalysesLabel          matlab.ui.control.Label
        StatusLamp             matlab.ui.control.Lamp
        CNIImagequalityLabel   matlab.ui.control.Label
        AcqlabelsListBoxLabel  matlab.ui.control.Label
        AcqlabelsListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        cni  =[]; % scitran channel to the cni site
        qaSessions = []; % The sessions since the specified time.
    end
    
    properties (Access = public)
        qaData = [];    % Struct with all the qaData
        qaProject = []; % Flywheel project container
        acqLabels = {}  % The labels found in the acquisitions
    end
    
    methods (Access = public)
        
        function acq = qaAcqSort(app,varargin)
            %% Now we could pull out the variables we want
            
            % Find the selected acq label
            acqLabel = app.AcqlabelsListBox.Value;
            % stPrint(app.qaData,'acquisition');
            
            acq = stSelect(app.qaData,'acquisition',acqLabel);
            % fprintf('%d qaData labeled: %s\n',numel(acq),acqLabel);
        end
        
        % Refresh the entries in the UI
        function refresh(app)
            
            % Set the text.
            app.AnalysesLabel.Text = ...
                sprintf('%d Analyses (%d Sessions)',...
                numel(app.qaData),numel(app.qaSessions));
            
            % Set the acquisition labels
            if isempty(app.acqLabels)
                % Create the labels
                allLabels = cell(numel(app.qaData,1));
                for ii=1:numel(app.qaData)
                    allLabels{ii} = app.qaData{ii}.acquisition;
                end
                app.acqLabels = sort(unique(allLabels));
            end
            
            app.AcqlabelsListBox.Items = app.acqLabels;
            app.AcqlabelsListBox.Value = app.acqLabels{1};
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(which('scitran'))
                warning('No scitran on your path. You can load and plot local data, but not interact with Flywheel.');
            end
            if isempty(app.cni)
                % Create the channel to Flywheel
                app.cni = scitran('cni');
            end
            % We only allow interaction with the QA project for now.  We
            % may generalize in the future.
            app.qaProject = app.cni.lookup('cni/qa');
        end

        % Button pushed function: RetrieveQAButton
        function qaRetrieve(app, event)
            % Go to Flywheel and find the relevant sessions.
            % Then build
            % We should check whether scitran is on the path and qaProject
            % exists.  If not, this button should be disabled.
            
            % These could become parameters
            analysisLabel = 'cni-tsnr';
            resultFile    = 'result';
            
            % The sessions after a certain date.  I have not yet
            % implemented the 'until' part.  Must ask for help on the key
            % string.
            since = sprintf('created>%s',app.SinceDatePicker.Value);
            % until = sprintf('created<=%s',app.UntilDatePicker.Value);
            % since = sprintf('created>%s & created >=%s',app.SinceDatePicker.Value,app.UntilDatePicker.Value);
            
            app.StatusLamp.Color = [1 1 0]; drawnow;
            app.qaSessions = app.qaProject.sessions.find(since);
            fprintf('Found %d sessions since %s\n',numel(app.qaSessions),since);
            
            % Pull the data from the sessions.  This involves downloading
            % the json file with the result.  It would be better, in the
            % future, to have the key snr and sfnr parameters written into
            % the metadata of the acquisitions or sessions or somewhere.
            qq = 1;
            allAcqLabels = {};
            for ss=1:numel(app.qaSessions)
                
                % Get the analyses
                qaAnalyses = app.qaSessions{ss}.analyses();
                if ~isempty(qaAnalyses)
                    
                    % This is the name of the analysis
                    qaAnalyses = stSelect(qaAnalyses,'label',analysisLabel);
                    if ~isempty(qaAnalyses)
                        for aa = 1:numel(qaAnalyses)
                            % Find the result file
                            if ~isempty(qaAnalyses{aa}.files)
                                thisFile = stSelect(qaAnalyses{aa}.files,'name',resultFile);
                            else
                                thisFile = '';
                            end
                            
                            if isempty(thisFile)
                                fprintf('No %s.json file found Analysis %d \n',resultFile,aa);
                            else
                                % fprintf('Analyses with a result file found in session %d\n',ss);
                                
                                % Download the file and read its contents
                                thisFile{1}.download('result.json');
                                tmp = jsonread('result.json');
                                tmp.created = qaAnalyses{aa}.created;
                                
                                %% Find which acquisition has the input file from the analyses
                                
                                % The acquisition label should be the same when we plot the snr or sfnr.
                                fileid = qaAnalyses{aa}.inputs{1}.id;
                                thisAcq = app.cni.search('acquisition','fileid',fileid,'container',true);
                                if ~isempty(thisAcq)
                                    tmp.acquisition =  thisAcq{1}.label;
                                    allAcqLabels{end+1} = thisAcq{1}.label;
                                else
                                    fprintf('No acquisition found that contains the analysis (%d) input file\n',aa);
                                    tmp.acquisition = 'Unknown acq';
                                    allAcqLabels{end+1} = 'Unknown acq';
                                end
                                app.qaData{qq} = tmp;
                                qq = qq+1;
                                app.AnalysesLabel.Text = ...
                                    sprintf('%d Analyses (%d of %d Sessions)',...
                                    qq-1,ss,numel(app.qaSessions));
                                drawnow;
                            end
                        end
                    end
                end
                
                % Sort and unique the acquisition labels.  Not sure how
                % this can be empty.
                if ~isempty(allAcqLabels)
                    app.acqLabels = unique(sort(allAcqLabels));
                    app.AcqlabelsListBox.Items = app.acqLabels;
                    app.AcqlabelsListBox.Value = app.acqLabels{1};
                end
                
            end
            app.StatusLamp.Color = [0 1 0]; drawnow;
        end

        % Menu selected function: App2workspaceMenu
        function app2ws(app, event)
            fprintf('Assigning the variable cniQA into the work space.\n');
            assignin('base','cniQA',app);
        end

        % Menu selected function: LoadQAdataMenu
        function qaLoad(app, event)
            % Perhaps we should save the path to the file as well.
            % We just find the file and load the qaData variable.
            
            % User selects the file
            [filename,filepath] = uigetfile('*.mat');  %open a mat file
            try
                tmp = load(fullfile(filepath,filename),'qaData');
            catch
                warning('File does not contain qaData variable');
                return;
            end
            app.qaData = tmp.qaData;
            
            % Now set the min and max dates in the date picker
            since = app.qaData{1}.created;
            % until = app.qaData{1}.created;
            for ii=1:numel(app.qaData)
                since = min(app.qaData{ii}.created, since);
                % until = max(app.qaData{ii}.created, until);
            end
            app.SinceDatePicker.Value = since;
            % app.UntilDatePicker.Value = until;
            
            refresh(app);
        end

        % Value changed function: AcqlabelsListBox
        function qaPlotCallback(app, event)
            % Not sure how to organize the plotting.  More UI elements
            % needed.
            
            % We need to select the figure
            acq = qaAcqSort(app);
            
            if isempty(acq)
                warning('No acquisitions with this label');
                return;
            end
            
            s = zeros(numel(acq),1);
            for ii=1:numel(acq)
                s(ii) = str2double(acq{ii}.sfnr_center);
                theDate(ii) = acq{ii}.created;
            end
            
            plot(app.mainPlot,theDate,s,'-o','LineWidth',2);
            grid(app.mainPlot,'on');
            ylabel(app.mainPlot,'sfnr center');
            xlabel(app.mainPlot,'');
        end

        % Menu selected function: QuitMenu
        function menuFileQuit(app, event)
            delete(app);
        end

        % Menu selected function: SaveQAdataMenu
        function qaSave(app, event)
            % Write out the qaData into current directory
            str = app.SinceDatePicker.Value;
            [fname,thePath] = uiputfile(sprintf('qaData-%s.mat',str));
            qaData = app.qaData;
            save(fullfile(thePath,fname),'qaData');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.mainPlot = uiaxes(app.UIFigure);
            title(app.mainPlot, 'Scan Type')
            xlabel(app.mainPlot, 'Date')
            ylabel(app.mainPlot, 'SNR')
            zlabel(app.mainPlot, 'Z')
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadQAdataMenu = uimenu(app.FileMenu);
            app.LoadQAdataMenu.MenuSelectedFcn = createCallbackFcn(app, @qaLoad, true);
            app.LoadQAdataMenu.Text = 'Load QA data';
            app.SaveQAdataMenu = uimenu(app.FileMenu);
            app.SaveQAdataMenu.MenuSelectedFcn = createCallbackFcn(app, @qaSave, true);
            app.SaveQAdataMenu.Text = 'Save QA data';
            app.RefreshMenu = uimenu(app.FileMenu);
            app.RefreshMenu.Text = 'Refresh';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @menuFileQuit, true);
            app.QuitMenu.Text = 'Quit';
            app.FlywheelMenu = uimenu(app.UIFigure);
            app.FlywheelMenu.Text = 'Flywheel';
            app.SigninMenu = uimenu(app.FlywheelMenu);
            app.SigninMenu.Text = 'Sign in';
            app.ProjectlabelMenu = uimenu(app.FlywheelMenu);
            app.ProjectlabelMenu.Text = 'Project label';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.App2workspaceMenu = uimenu(app.ToolsMenu);
            app.App2workspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @app2ws, true);
            app.App2workspaceMenu.Text = 'App 2 workspace';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.TBDMenu = uimenu(app.PlotMenu);
            app.TBDMenu.Text = 'TBD';
            app.SelectdataPanel = uipanel(app.UIFigure);
            app.SelectdataPanel.Title = 'Select data';
            app.RetrieveQAButton = uibutton(app.SelectdataPanel, 'push');
            app.RetrieveQAButton.ButtonPushedFcn = createCallbackFcn(app, @qaRetrieve, true);
            app.RetrieveQAButton.Text = 'Retrieve QA';
            app.SinceDatePickerLabel = uilabel(app.SelectdataPanel);
            app.SinceDatePickerLabel.Text = 'Since';
            app.SinceDatePicker = uidatepicker(app.SelectdataPanel);
            app.SinceDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.UntilDatePickerLabel = uilabel(app.SelectdataPanel);
            app.UntilDatePickerLabel.Enable = 'off';
            app.UntilDatePickerLabel.Text = 'Until';
            app.UntilDatePicker = uidatepicker(app.SelectdataPanel);
            app.UntilDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.UntilDatePicker.Enable = 'off';
            app.UntilDatePicker.Visible = 'off';
            app.AnalysesLabel = uilabel(app.SelectdataPanel);
            app.AnalysesLabel.Text = 'Analyses';
            app.StatusLamp = uilamp(app.SelectdataPanel);
            app.CNIImagequalityLabel = uilabel(app.UIFigure);
            app.CNIImagequalityLabel.Text = 'CNI Image quality';
            app.AcqlabelsListBoxLabel = uilabel(app.UIFigure);
            app.AcqlabelsListBoxLabel.Text = 'Acq labels';
            app.AcqlabelsListBox = uilistbox(app.UIFigure);
            app.AcqlabelsListBox.Items = {};
            app.AcqlabelsListBox.ValueChangedFcn = createCallbackFcn(app, @qaPlotCallback, true);
            app.AcqlabelsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cniQA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end